classdef PassiveRadarApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AppStateMenu                    matlab.ui.container.Menu
        USRPMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        ProcessingTab                   matlab.ui.container.Tab
        PlottingPanel                   matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        CrossAmbiguityFunctionTab       matlab.ui.container.Tab
        Tree                            matlab.ui.container.CheckBoxTree
        Plots                           matlab.ui.container.TreeNode
        TimeRef                         matlab.ui.container.TreeNode
        TimeSurv                        matlab.ui.container.TreeNode
        SpectrumRef                     matlab.ui.container.TreeNode
        SpectrumSurv                    matlab.ui.container.TreeNode
        CorrelationdelayLabel           matlab.ui.control.Label
        CorrelationDelayEditField       matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        CafVelocityRangeLabel           matlab.ui.control.Label
        CafVelocityRangeEditField       matlab.ui.control.NumericEditField
        FFTSizeEditFieldLabel           matlab.ui.control.Label
        FFTSizeEditField                matlab.ui.control.NumericEditField
        CafDelayBackwardEditFieldLabel  matlab.ui.control.Label
        CafDelayBackwardEditField       matlab.ui.control.NumericEditField
        CafDelayForwardEditFieldLabel   matlab.ui.control.Label
        CafDelayForwardEditField        matlab.ui.control.NumericEditField
        UIAxes_xcorr                    matlab.ui.control.UIAxes
        UIAxes_CAF                      matlab.ui.control.UIAxes
        AlgorithmsPanel                 matlab.ui.container.Panel
        TrimSignalsCheckBox             matlab.ui.control.CheckBox
        UseWindowingCheckBox            matlab.ui.control.CheckBox
        UseECACheckBox                  matlab.ui.control.CheckBox
        STOPButton                      matlab.ui.control.Button
        STARTButton                     matlab.ui.control.Button
        ModeDropDown                    matlab.ui.control.DropDown
        ModeDropDownLabel               matlab.ui.control.Label
        ECALengthEditField              matlab.ui.control.NumericEditField
        ECALengthEditFieldLabel         matlab.ui.control.Label
        LoggerWorkspacePanel            matlab.ui.container.Panel
        LoggerTextArea                  matlab.ui.control.TextArea
        FilesRecordingModePanel         matlab.ui.container.Panel
        LoadFolderButton                matlab.ui.control.Button
        LoadFilesButton                 matlab.ui.control.Button
        SignalsParametersPanel          matlab.ui.container.Panel
        SignalLengthEditField           matlab.ui.control.NumericEditField
        SignalLengthEditFieldLabel      matlab.ui.control.Label
        FcEditField                     matlab.ui.control.NumericEditField
        FcEditFieldLabel                matlab.ui.control.Label
        FsEditField                     matlab.ui.control.NumericEditField
        FsEditFieldLabel                matlab.ui.control.Label
        SimulationTab                   matlab.ui.container.Tab
        sim_StartButton                 matlab.ui.control.Button
        sim_TargetsPanel                matlab.ui.container.Panel
        Attenuation2dBEditField         matlab.ui.control.NumericEditField
        Attenuation2dBEditFieldLabel    matlab.ui.control.Label
        Attenuation1dBEditField         matlab.ui.control.NumericEditField
        Attenuation1dBEditFieldLabel    matlab.ui.control.Label
        Velocity2msEditField            matlab.ui.control.NumericEditField
        Velocity2msEditFieldLabel       matlab.ui.control.Label
        Velocity1msEditField            matlab.ui.control.NumericEditField
        Velocity1msEditFieldLabel       matlab.ui.control.Label
        Range2mEditField                matlab.ui.control.NumericEditField
        Range2mEditFieldLabel           matlab.ui.control.Label
        Range1mEditField                matlab.ui.control.NumericEditField
        Range1mEditFieldLabel           matlab.ui.control.Label
        sim_ParametersPanel             matlab.ui.container.Panel
        sim_IntegrationTimesEditField   matlab.ui.control.NumericEditField
        IntegrationTimesEditFieldLabel  matlab.ui.control.Label
        sim_FcEditField                 matlab.ui.control.NumericEditField
        FcEditField_2Label              matlab.ui.control.Label
        sim_FsEditField                 matlab.ui.control.NumericEditField
        FsEditField_2Label              matlab.ui.control.Label
        sim_SignalTypeDropDown          matlab.ui.control.DropDown
        SignalTypeDropDownLabel         matlab.ui.control.Label
        MapTab                          matlab.ui.container.Tab
        CLEANTab                        matlab.ui.container.Tab
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    properties (Access = public)
        Engine RadarEngine.RadarEngine
        DataProvider
        %Processor RadarProcessor
        Simulator RadarSimulator

        LoopTimer
        History
        Mode
        isProcessing

        config
        LogFileName
        PlotsFlag
    end
    
    properties (Access = private)
        processingFlags RadarEngine.ProcessingFlags 
        filterParams % Description
    end

    methods (Access = private)
        function startupLogger(app,logFileName)
            app.LogFileName = logFileName;
            fid = fopen(app.LogFileName, 'w');
            fprintf(fid, '=== PASSIVE RADAR APP MATLAB STARTED ===\n');
            fclose(fid);

            % if ispc
            %     cmd = sprintf('start powershell -NoExit -Command "Get-Content %s -Wait"', app.LogFileName);
            %     system(cmd);
            % elseif isunix
            %     cmd = sprintf('env LD_LIBRARY_PATH="" gnome-terminal -- bash -c "tail -f ''%s''" &', app.LogFileName);
            %     system(cmd)
            % end
        end
        function startupConfig(app)
            app.config = app.readConfig();
            app.setConfig();
        end
        function startupDataProvider(app)
            initialMode = app.ModeDropDown.Value;
            app.changeAppMode(initialMode);
        end
        function startupEngine(app)
            app.Engine = RadarEngine.RadarEngine(app.processingFlags,app.filterParams);
            app.Engine.setFilterParams(app.filterParams);
            app.logMessage("Created Radar Engine");
        end

        function logMessage(app, text, mode)
            if nargin < 3
                mode = "INFO";
            end

            timestamp = char(datetime('now','Format','HH:mm:ss.SSS'));
            mode = upper(string(mode));

            switch mode
                case "INFO"
                    prefix  = '[INFO] ';
                case "WARN"
                    prefix  = '[WARN] ';
                case "ERROR"
                    prefix  = '[ERROR]';
                case "SUCCESS"
                    prefix  = '[ OK ] ';
                case "DEBUG"
                    prefix = '[DEBUG]';
                otherwise
                    prefix = sprintf('[%s]', mode);
            end

            fullText = sprintf('%s %s %s', timestamp, prefix, text);

            fid = fopen(app.LogFileName, "a");
            if fid ~=-1
                fprintf(fid, '%s\n', fullText);
            else
                return;
            end

            if mode == "ERROR"
                fprintf(2, '%s\n', fullText);
            else
                fprintf(1, '%s\n', fullText);
            end
            %currentLogs = app.LoggerTextArea.Value;

            %app.LoggerTextArea.Value = [currentLogs; {fullText}];
            %app.LoggerTextArea.FontColor = color;
            %scroll(app.LoggerTextArea,'bottom');
            %disp(fulltext);
            %drawnow limitrate;

        end
        function config = readConfig(app)
            configFile  = "config.json";
            config      = struct();
            p_signals   = struct();
            p_filters   = struct();
            p_caf       = struct();

            p_signals.fs                 = 10e6;
            p_signals.fc                 = 500e6;
            p_signals.length_s           = 1;   % in seconds [m]
            p_signals.numSamples         = p_signals.fs * p_signals.length_s;
            p_signals.ref_startSample    = 0;
            p_signals.ref_stopSample     = p_signals.numSamples;
            p_signals.surv_startSample   = 0;
            p_signals.surv_stopSample    = p_signals.numSamples;

            p_filters.orderNear          = 5;
            p_filters.forgettingFactNear = 0.9996;
            p_filters.orderFar           = 100;
            p_filters.forgettingFactFar  = 0.99999;

            p_caf.rangeStart_m  = 0;     % meters [m]
            p_caf.rangeStop_m   = 200;   % meters [m]
            p_caf.velRange_ms   = 300;   % meters per seconds [m/s]
            p_caf.fftSize       = 2048;
            p_caf.corrRange     = 2000;  % samples

            config.p_signals    = p_signals;
            config.p_filters    = p_filters;
            config.p_caf        = p_caf;

            if isfile(configFile)
                try
                    rawText = fileread(configFile);
                    fileCfg = jsondecode(rawText);

                    fields = fieldnames(fileCfg);

                    for i = 1:length(fields)
                        config.(fields{i}) = fileCfg.(fields{i});
                    end
                    app.logMessage('Configuration Loaded from config.json');

                catch ME
                    app.logMessage("Configuration loading failed", "WARN")
                end
            else
                app.logMessage("No configuration file. Using default values");
            end

        end
        function setConfig(app)
            app.logMessage("Setting values from config");

            app.FsEditField.Value                       = app.config.p_signals.fs;
            app.FcEditField.Value                       = app.config.p_signals.fc;
            app.SignalLengthEditField.Value             = app.config.p_signals.length_s;


            app.ECALengthEditField.Value              = app.config.p_filters.orderNear;

            app.CafDelayBackwardEditField.Value         = app.config.p_caf.rangeStart_m;
            app.CafDelayForwardEditField.Value          = app.config.p_caf.rangeStop_m;
            app.CafVelocityRangeEditField.Value         = app.config.p_caf.velRange_ms;
            app.FFTSizeEditField.Value                  = app.config.p_caf.fftSize;
            app.CorrelationDelayEditField.Value         = app.config.p_caf.corrRange;

            app.processingFlags = RadarEngine.ProcessingFlags();
            app.filterParams = app.ECALengthEditField.Value;
        end

        % function syncUItoSimulator(app)
        %     if isempty(app.Simulator)
        %         app.Simulator = RadarSimulator();
        %         app.logMessage("Simulator object created");
        %     end
        %     cfgSig = struct;
        %     targets() = 0;
        %
        %     cfgSig.Type     = app.sim_SignalTypeDropDown.Value;
        %     cfgSig.Fs       = app.sim_FsEditField.Value;
        %     cfgSig.Fc       = app.sim_FcEditField.Value;
        %     cfgSig.inTime   = app.sim_IntegrationTimesEditField.Value;
        %
        %     app.Simulator.SigConfig = cfgSig;
        %
        %     app.Simulator.addTarget(RadarTarget(1, app))
        % end

        function changeAppMode(app, newMode)
            app.logMessage("Changing app to mode: " + upper(newMode));

            if ~isempty(app.LoopTimer) && isvalid(app.LoopTimer)
                stop(app.LoopTimer);
                app.logMessage("LoopTimer STOPPED");
            end
            app.Mode = newMode;
            switch app.Mode
                case "Recording"
                    app.DataProvider = FileDataProvider();
                case "Real-Time"

                    app.DataProvider = CppBufferDataProvider();
                otherwise
                    app.logMessage("Unknown Mode","WARN");
                    return;
            end
            app.logMessage("App mode changed succesfully", "SUCCESS");
        end

        function processingStep(app)
            try
                [ref,surv,params, success] = app.DataProvider.getNextChunk();
                app.logMessage(sprintf("Processed %d / %d files", app.DataProvider.CurrentIndex, app.DataProvider.numFiles));
                disp(length(ref));
                disp(length(surv));
                app.Engine.setSignals(ref, surv);
                app.Engine.processSignals(params);
                
                xcor =  xcorr(app.Engine.xRef,app.Engine.xSurv, 1000);
                lags = -1000 : 1000;
                plot(app.UIAxes_xcorr,lags,abs(xcor));
                drawnow limitrate;
            catch ME
                app.emergencyStop();
                disp(ME.message);
            end
        end

        function updateFileProvider(app,paths)
            app.DataProvider.configure(paths,struct('DataSource', 'Is3p'))
        end

        function updateUIState(app)

        end

        function updateUI(app)
            imagesc(app.UIAxes_CAF, ...
                app.Engine.DopplerAxis, ...
                app.Engine.RangeAxis, ...
                app.Engine.CafMap);

            app.UIAxes_CAF.YDir = 'normal';

            maxPeak = max(app.Engine.CafMap, [], 'all');
            dynamicRange = 50;

            clim(app.UIAxes_CAF, [maxPeak - dynamicRange, maxPeak]);

            xlabel(app.UIAxes_CAF, 'Przesunięcie Dopplera [Hz]');
            ylabel(app.UIAxes_CAF, 'Odległość bistatyczna [km]');
            colorbar(app.UIAxes_CAF);

            drawnow limitrate;
        end

        function emergencyStop(app)

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all; clc;
            %app.LoggerTextArea.Editable = "off";
            app.startupLogger("radar_session.log");
            app.logMessage("Startup Function called");
            app.startupConfig();
            app.startupEngine();
            app.startupDataProvider();

            app.LoopTimer = timer(...
                'ExecutionMode', 'fixedSpacing', 'Period', 0.05, ...
                'TimerFcn', @(~,~) app.processingStep(), ...
                'ErrorFcn', @(~,~) app.emergencyStop());

            %app.Processor = RadarProcessor(app.config.p_signals.fs, app.config.p_signals.fc);
            %app.Simulator = RadarSimulator();

            addlistener(app.Engine, 'DataProcessed', @(~,~) app.updateUI());
            app.isProcessing = false;
        end

        % Button pushed function: LoadFilesButton
        function LoadFilesButtonPushed(app, event)
            app.logMessage("Loading signals from files...");
            typesFilter = {'*.bin'; '*.mat'; '*.dat'};
            [files, path] = uigetfile(typesFilter,"Choose raw signals",'MultiSelect','on');
            if isequal(files, 0)
                app.logMessage("Loading signals canceled");
                return;
            end

            if ischar(files)
                files = {files};
            end

            fullPaths = cell(1,length(files));
            for i = 1:length(files)
                fullPaths{i} = fullfile(path,files{i});
            end
            app.updateFileProvider(fullPaths);
            app.logMessage("Loaded " + length(fullPaths) + " files", "SUCCESS");
        end

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
            app.logMessage("Loading signals from folder...");
            folderPath = uigetdir('', 'Choose recording folder');

            if isequal(folderPath, 0)
                app.logMessage("Loading folder canceled");
                return;
            end


            filePattern = fullfile(folderPath, '*.bin');
            recFiles = dir(filePattern);

            if isempty(recFiles)
                uialert(app.UIFigure, 'Choosed folder hase inproper files format (*.bin)', 'ERROR');
                return;
            end

            fullPaths = cell(1, length(recFiles));
            for i = 1:length(recFiles)
                fullPaths{i} = fullfile(folderPath, recFiles(i).name);
            end

            app.updateFileProvider(fullPaths);
            app.logMessage("Loaded " + length(fullPaths) + " files", "SUCCESS");
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            if ~app.isProcessing
                if isempty(app.DataProvider)
                    uialert(app.UIFigure, "Choose app mode", "No mode choosed");
                    return;
                end
                if app.Mode == "Recording" && app.DataProvider.numFiles == 0
                    uialert(app.UIFigure, "There is no files to process", "Faulty DataProvider");
                    return;
                end

                app.isProcessing = true;
                app.updateUIState();
                app.logMessage("Starting processing loop...");

                if strcmp(app.LoopTimer.Running, 'off')
                    start(app.LoopTimer);
                end

            else
                return;
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            if app.isProcessing == true
                app.isProcessing = false;
                app.updateUIState();
                app.logMessage("Processing loop stopped");

                if strcmp(app.LoopTimer.Running, 'on')
                    stop(app.LoopTimer);
                end
            else
                uialert(app.UIFigure, "Processing is already stopped", "Timer is not running");
            end
        end

        % Button pushed function: sim_StartButton
        function sim_StartButtonPushed(app, event)
            app.logMessage("Running the simulation");

        end

        % Value changed function: sim_FsEditField
        function sim_FsEditFieldValueChanged(app, event)
            value = app.sim_FsEditField.Value;

        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            selectedMode = app.ModeDropDown.Value;
            app.changeAppMode(selectedMode);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if strcmp(app.LoopTimer.Running, 'on')
                stop(app.LoopTimer);
            end
            delete(app)
        end

        % Value changed function: UseECACheckBox
        function UseECACheckBoxValueChanged(app, event)
            value = app.UseECACheckBox.Value;
            app.processingFlags.useEca = value;
            app.Engine.ProcessingFlags = app.processingFlags;
        end

        % Value changed function: UseWindowingCheckBox
        function UseWindowingCheckBoxValueChanged(app, event)
            value = app.UseWindowingCheckBox.Value;
            app.processingFlags.isWindow = value;
            app.Engine.ProcessingFlags = app.processingFlags;
        end

        % Value changed function: TrimSignalsCheckBox
        function TrimSignalsCheckBoxValueChanged(app, event)
            value = app.TrimSignalsCheckBox.Value;
            app.processingFlags.trimSignals = value;
            app.Engine.ProcessingFlags = app.processingFlags;
        end

        % Value changed function: ECALengthEditField
        function ECALengthEditFieldValueChanged(app, event)
            value = app.ECALengthEditField.Value;
            app.filterParams = value;
            app.Engine.setFilterParams(app.filterParams);
            app.logMessage(sprintf("Changed ECA Filter length to %d", app.filterParams));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AppStateMenu = uimenu(app.UIFigure);
            app.AppStateMenu.Text = 'App State';
            app.USRPMenu = uimenu(app.UIFigure);
            app.USRPMenu.Text = 'USRP';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.SignalsParametersPanel = uipanel(app.ProcessingTab);
            app.SignalsParametersPanel.Title = 'Signals Parameters';
            app.FsEditFieldLabel = uilabel(app.SignalsParametersPanel);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.SignalsParametersPanel, 'numeric');
            app.FcEditFieldLabel = uilabel(app.SignalsParametersPanel);
            app.FcEditFieldLabel.Text = 'Fc';
            app.FcEditField = uieditfield(app.SignalsParametersPanel, 'numeric');
            app.SignalLengthEditFieldLabel = uilabel(app.SignalsParametersPanel);
            app.SignalLengthEditFieldLabel.Text = 'Signal Length';
            app.SignalLengthEditField = uieditfield(app.SignalsParametersPanel, 'numeric');
            app.FilesRecordingModePanel = uipanel(app.ProcessingTab);
            app.FilesRecordingModePanel.Title = 'Files (Recording Mode)';
            app.LoadFilesButton = uibutton(app.FilesRecordingModePanel, 'push');
            app.LoadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFilesButtonPushed, true);
            app.LoadFilesButton.Text = 'Load Files';
            app.LoadFolderButton = uibutton(app.FilesRecordingModePanel, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Text = 'Load Folder';
            app.LoggerWorkspacePanel = uipanel(app.ProcessingTab);
            app.LoggerWorkspacePanel.Title = 'Logger / Workspace';
            app.LoggerTextArea = uitextarea(app.LoggerWorkspacePanel);
            app.AlgorithmsPanel = uipanel(app.ProcessingTab);
            app.AlgorithmsPanel.Title = 'Algorithms';
            app.ECALengthEditFieldLabel = uilabel(app.AlgorithmsPanel);
            app.ECALengthEditFieldLabel.Text = 'ECA Length';
            app.ECALengthEditField = uieditfield(app.AlgorithmsPanel, 'numeric');
            app.ECALengthEditField.ValueChangedFcn = createCallbackFcn(app, @ECALengthEditFieldValueChanged, true);
            app.ModeDropDownLabel = uilabel(app.AlgorithmsPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.AlgorithmsPanel);
            app.ModeDropDown.Items = {'Recording', 'Real-Time'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Recording';
            app.STARTButton = uibutton(app.AlgorithmsPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.AlgorithmsPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.UseECACheckBox = uicheckbox(app.AlgorithmsPanel);
            app.UseECACheckBox.ValueChangedFcn = createCallbackFcn(app, @UseECACheckBoxValueChanged, true);
            app.UseECACheckBox.Text = 'Use ECA';
            app.UseWindowingCheckBox = uicheckbox(app.AlgorithmsPanel);
            app.UseWindowingCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseWindowingCheckBoxValueChanged, true);
            app.UseWindowingCheckBox.Text = 'Use Windowing';
            app.TrimSignalsCheckBox = uicheckbox(app.AlgorithmsPanel);
            app.TrimSignalsCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrimSignalsCheckBoxValueChanged, true);
            app.TrimSignalsCheckBox.Text = 'Trim Signals';
            app.PlottingPanel = uipanel(app.ProcessingTab);
            app.PlottingPanel.Title = 'Plotting';
            app.TabGroup2 = uitabgroup(app.PlottingPanel);
            app.CrossAmbiguityFunctionTab = uitab(app.TabGroup2);
            app.CrossAmbiguityFunctionTab.Title = 'Cross Ambiguity Function';
            app.UIAxes_CAF = uiaxes(app.CrossAmbiguityFunctionTab);
            title(app.UIAxes_CAF, 'CAF')
            xlabel(app.UIAxes_CAF, 'X')
            ylabel(app.UIAxes_CAF, 'Y')
            zlabel(app.UIAxes_CAF, 'Z')
            app.UIAxes_xcorr = uiaxes(app.CrossAmbiguityFunctionTab);
            title(app.UIAxes_xcorr, 'Cross Correlation')
            xlabel(app.UIAxes_xcorr, 'X')
            ylabel(app.UIAxes_xcorr, 'Y')
            zlabel(app.UIAxes_xcorr, 'Z')
            app.CafDelayForwardEditField = uieditfield(app.CrossAmbiguityFunctionTab, 'numeric');
            app.CafDelayForwardEditFieldLabel = uilabel(app.CrossAmbiguityFunctionTab);
            app.CafDelayForwardEditFieldLabel.Text = 'Caf Delay Forward';
            app.CafDelayBackwardEditField = uieditfield(app.CrossAmbiguityFunctionTab, 'numeric');
            app.CafDelayBackwardEditFieldLabel = uilabel(app.CrossAmbiguityFunctionTab);
            app.CafDelayBackwardEditFieldLabel.Text = 'Caf Delay Backward';
            app.FFTSizeEditField = uieditfield(app.CrossAmbiguityFunctionTab, 'numeric');
            app.FFTSizeEditFieldLabel = uilabel(app.CrossAmbiguityFunctionTab);
            app.FFTSizeEditFieldLabel.Text = 'FFT Size';
            app.CafVelocityRangeEditField = uieditfield(app.CrossAmbiguityFunctionTab, 'numeric');
            app.CafVelocityRangeLabel = uilabel(app.CrossAmbiguityFunctionTab);
            app.CafVelocityRangeLabel.Text = 'Caf Velocity Range (+/-)';
            app.EditField = uieditfield(app.CrossAmbiguityFunctionTab, 'numeric');
            app.EditFieldLabel = uilabel(app.CrossAmbiguityFunctionTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.CorrelationDelayEditField = uieditfield(app.CrossAmbiguityFunctionTab, 'numeric');
            app.CorrelationdelayLabel = uilabel(app.CrossAmbiguityFunctionTab);
            app.CorrelationdelayLabel.Text = {'Correlation'; 'Delay'};
            app.Tree = uitree(app.CrossAmbiguityFunctionTab, 'checkbox');
            app.Plots = uitreenode(app.Tree);
            app.Plots.Text = 'Node';
            app.TimeRef = uitreenode(app.Plots);
            app.TimeRef.Text = 'Node2';
            app.TimeSurv = uitreenode(app.Plots);
            app.TimeSurv.Text = 'Node3';
            app.SpectrumRef = uitreenode(app.Plots);
            app.SpectrumRef.Text = 'Node4';
            app.SpectrumSurv = uitreenode(app.Plots);
            app.SpectrumSurv.Text = 'Node5';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.sim_ParametersPanel = uipanel(app.SimulationTab);
            app.sim_ParametersPanel.Title = 'Parameters';
            app.SignalTypeDropDownLabel = uilabel(app.sim_ParametersPanel);
            app.SignalTypeDropDownLabel.Text = 'Signal Type';
            app.sim_SignalTypeDropDown = uidropdown(app.sim_ParametersPanel);
            app.sim_SignalTypeDropDown.Items = {'FM', 'DAB+', 'DVB-T'};
            app.sim_SignalTypeDropDown.Value = 'FM';
            app.FsEditField_2Label = uilabel(app.sim_ParametersPanel);
            app.FsEditField_2Label.Text = 'Fs';
            app.sim_FsEditField = uieditfield(app.sim_ParametersPanel, 'numeric');
            app.sim_FsEditField.ValueChangedFcn = createCallbackFcn(app, @sim_FsEditFieldValueChanged, true);
            app.sim_FsEditField.Value = 8600000;
            app.FcEditField_2Label = uilabel(app.sim_ParametersPanel);
            app.FcEditField_2Label.Text = 'Fc';
            app.sim_FcEditField = uieditfield(app.sim_ParametersPanel, 'numeric');
            app.sim_FcEditField.Value = 500000000;
            app.IntegrationTimesEditFieldLabel = uilabel(app.sim_ParametersPanel);
            app.IntegrationTimesEditFieldLabel.Text = 'Integration Time [s]';
            app.sim_IntegrationTimesEditField = uieditfield(app.sim_ParametersPanel, 'numeric');
            app.sim_IntegrationTimesEditField.Value = 1;
            app.sim_TargetsPanel = uipanel(app.SimulationTab);
            app.sim_TargetsPanel.Title = 'Targets';
            app.Range1mEditFieldLabel = uilabel(app.sim_TargetsPanel);
            app.Range1mEditFieldLabel.Text = 'Range 1 [m]';
            app.Range1mEditField = uieditfield(app.sim_TargetsPanel, 'numeric');
            app.Range2mEditFieldLabel = uilabel(app.sim_TargetsPanel);
            app.Range2mEditFieldLabel.Text = 'Range 2 [m]';
            app.Range2mEditField = uieditfield(app.sim_TargetsPanel, 'numeric');
            app.Velocity1msEditFieldLabel = uilabel(app.sim_TargetsPanel);
            app.Velocity1msEditFieldLabel.Text = 'Velocity 1 [m/s]';
            app.Velocity1msEditField = uieditfield(app.sim_TargetsPanel, 'numeric');
            app.Velocity2msEditFieldLabel = uilabel(app.sim_TargetsPanel);
            app.Velocity2msEditFieldLabel.Text = 'Velocity 2 [m/s]';
            app.Velocity2msEditField = uieditfield(app.sim_TargetsPanel, 'numeric');
            app.Attenuation1dBEditFieldLabel = uilabel(app.sim_TargetsPanel);
            app.Attenuation1dBEditFieldLabel.Text = 'Attenuation 1 [dB]';
            app.Attenuation1dBEditField = uieditfield(app.sim_TargetsPanel, 'numeric');
            app.Attenuation2dBEditFieldLabel = uilabel(app.sim_TargetsPanel);
            app.Attenuation2dBEditFieldLabel.Text = 'Attenuation 2 [dB]';
            app.Attenuation2dBEditField = uieditfield(app.sim_TargetsPanel, 'numeric');
            app.sim_StartButton = uibutton(app.SimulationTab, 'push');
            app.sim_StartButton.ButtonPushedFcn = createCallbackFcn(app, @sim_StartButtonPushed, true);
            app.sim_StartButton.Text = 'Start';
            app.MapTab = uitab(app.TabGroup);
            app.MapTab.Title = 'Map';
            app.CLEANTab = uitab(app.TabGroup);
            app.CLEANTab.Title = 'CLEAN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PassiveRadarApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end